classdef send_ble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Lamp                matlab.ui.control.Lamp
        Label               matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        Muscle3Switch       matlab.ui.control.Switch
        Muscle3SwitchLabel  matlab.ui.control.Label
        Muscle2Switch       matlab.ui.control.Switch
        Muscle2SwitchLabel  matlab.ui.control.Label
        Knob_3              matlab.ui.control.Knob
        Knob_2              matlab.ui.control.Knob
        Knob                matlab.ui.control.Knob
        Muscle1Switch       matlab.ui.control.Switch
        Muscle1SwitchLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        serialAddress = "/dev/tty.usbserial-DN03GO3K";
        bleAddress = "98B22EEE-F9CA-420E-9F0B-7976E1D0D356";
        
        %% startupFcn properties
        muscleOneThreshold = 1000;     % int ∈[0,1023]: min. muscleOneReading to enable muscle one
        muscleTwoThreshold = 1000;     % int ∈[0,1023]: min. muscleTwoReading to enable muscle two
        muscleThreeThreshold = 1000;   % int ∈[0,1023]: min. muscleThreeReading to enable muscle three
       
        %% StartButtonPushed properties
        serialMonitor;      % Arduino Mini ("Arduino 1") serial monitor
        iot;                % Arduino Nano ("Arduino 2") BLE object
        
        %muscleOneReading = 0;   % int ∈[0,1023]: voltage at A0
        %muscleTwoReading = 0;   % int ∈[0,1023]: voltage at A1
        %muscleThreeReading = 0; % int ∈[0,1023]: voltage at A2
        
        %muscleOneEnabled = false;   % bool: is muscle one being stimulated?
        %muscleTwoEnabled = false;   % bool: is muscle two being stimulated?
        %muscleThreeEnabled = false; % bool: is muscle three being stimulated?
        
        muscleOneVoltage = 0;   % int ∈[0,1023]: voltage stimulating muscle one 
        muscleTwoVoltage = 0;   % int ∈[0,1023]: voltage stimulating muscle two
        muscleThreeVoltage = 0; % int ∈[0,1023]: voltage stimulating muscle three
        
        muscleOneVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleTwoVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleThreeVoltageChar; % characteristic object - writing to this stimulates muscle one
        
    end
    
    methods (Access = private)
        function muscleOneHandler(app, voltageReading)
            knobValue = app.Knob.Value;
            
            % Adjust voltage if:
            %   1. voltageReading is below threshold and muscle voltage
            %       must be disabled
            %   2. voltageReading is above threshold but muscle voltage 
            %       does not match knob
            if (voltageReading < app.muscleOneThreshold) && (app.muscleOneVoltage ~= 0)
                write(app.muscleOneVoltageChar, 0);
                app.muscleOneVoltage = 0;
            elseif (voltageReading >= app.muscleOneThreshold) && (app.muscleOneVoltage ~= knobValue)
                write(app.muscleOneVoltageChar, app.Knob.Value)
                app.muscleOneVoltage = knobValue;
            end
        end
        
        function muscleTwoHandler(app, voltageReading)
            knobValue = app.Knob.Value;
            
            % Adjust voltage if:
            %   1. voltageReading is below threshold and muscle voltage
            %       must be disabled
            %   2. voltageReading is above threshold but muscle voltage 
            %       does not match knob
            if (voltageReading < app.muscleTwoThreshold) && (app.muscleTwoVoltage ~= 0)
                write(app.muscleTwoVoltageChar, 0);
                app.muscleTwoVoltage = 0;
            elseif (voltageReading >= app.muscleTwoThreshold) && (app.muscleTwoVoltage ~= knobValue)
                write(app.muscleTwoVoltageChar, app.Knob.Value)
                app.muscleTwoVoltage = knobValue;
            end
        end
        
        function muscleThreeHandler(app, voltageReading)
            knobValue = app.Knob.Value;
            
            % Adjust voltage if:
            %   1. voltageReading is below threshold and muscle voltage
            %       must be disabled
            %   2. voltageReading is above threshold but muscle voltage 
            %       does not match knob
            if (voltageReading < app.muscleThreeThreshold) && (app.muscleThreeVoltage ~= 0)
                write(app.muscleThreeVoltageChar, 0);
                app.muscleThreeVoltage = 0;
            elseif (voltageReading >= app.muscleThreeThreshold) && (app.muscleThreeVoltage ~= knobValue)
                write(app.muscleThreeVoltageChar, app.Knob.Value)
                app.muscleThreeVoltage = knobValue;
            end
        end

        
        function loop(app)
            while 1
                % Read serial monitor and decode value
                serialMonitorValue = fscanf(app.serialMonitor, '%s');
                muscleID = serialMonitorValue(1);
                voltageReading = str2num(serialMonitorValue(2:end));
                
                % Send serial monitor reading to corresponding muscle's handler
                switch muscleID
                    case 'A'
                        muscleOneHandler(app, voltageReading)
                    case 'B'
                        muscleTwoHandler(app, voltageReading)
                    case 'C'
                        muscleThreeHandler(app, voltageReading)
                end
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear; instrreset;
                     
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Initalize serial monitor  
            app.serialMonitor = serial(app.serialAddress);
            fopen(app.serialMonitor);
            
            % Initialize ble object
            app.iot = ble(app.bleAddress);
        
            % Initialize ble characteristics
            muscleVoltageServiceUUID = "19B10000-E8F2-537E-4F6C-D204768A1214";
            app.muscleOneVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B1");
            app.muscleTwoVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B2");
            app.muscleThreeVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B3");
            
            % Write 0 to muscle voltage charactericts
            write(app.muscleOneVoltageChar, 0);
            write(app.muscleTwoVoltageChar, 0);
            write(app.muscleThreeVoltageChar, 0);
     
            % Indicate 'ready' status
            app.Lamp.Color = [0.00 1.00 0.00];
            
            % Break off into main control
            loop(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Muscle1SwitchLabel = uilabel(app.UIFigure);
            app.Muscle1SwitchLabel.Text = 'Muscle 1';
            app.Muscle1Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle1Switch.ItemsData = {'0', '1'};
            app.Muscle1Switch.Value = '0';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 128];
            app.Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Knob_2.Limits = [0 128];
            app.Knob_3 = uiknob(app.UIFigure, 'continuous');
            app.Knob_3.Limits = [0 128];
            app.Knob_3.MajorTickLabels = {'0', '16', '32', '48', '64', '80', '96', '112', '128'};
            app.Muscle2SwitchLabel = uilabel(app.UIFigure);
            app.Muscle2SwitchLabel.Text = 'Muscle 2';
            app.Muscle2Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle2Switch.ItemsData = {'0', '1'};
            app.Muscle2Switch.Value = '0';
            app.Muscle3SwitchLabel = uilabel(app.UIFigure);
            app.Muscle3SwitchLabel.Text = 'Muscle 3';
            app.Muscle3Switch = uiswitch(app.UIFigure, 'slider');
            app.Muscle3Switch.ItemsData = {'0', '1'};
            app.Muscle3Switch.Value = '0';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end