classdef send_clock_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FrequencyHzSliderLabel  matlab.ui.control.Label
        FrequencyHzSlider       matlab.ui.control.Slider
        PulseWidthsSliderLabel  matlab.ui.control.Label
        PulseWidthsSlider       matlab.ui.control.Slider
        SendClockSignalButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        PrevFrequencyID % Description
    end
    
    % TODO
    % 1. Enable bluetooth
    % 2. Pulse width stays consistent between states (develop PrevFreqID)
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrequencyHzSlider
        function FrequencyChanged(app, event)
            FrequencyID = round(app.FrequencyHzSlider.Value);
            app.FrequencyHzSlider.Value = FrequencyID;
            if FrequencyID == 1
                app.PulseWidthsSlider.Limits = [0 2];
                app.PulseWidthsSlider.MajorTicks = [0 1 2];
                app.PulseWidthsSlider.MajorTickLabels = {'0', '128.5', '257'};
                app.PulseWidthsSlider.MinorTicks = [];
            elseif FrequencyID == 2
                app.PulseWidthsSlider.Limits = [0 4];
                app.PulseWidthsSlider.MajorTicks = [0 1 2 3 4];
                app.PulseWidthsSlider.MajorTickLabels = {'0', '64.25', '128.5', '192.75', '257', ''};
                app.PulseWidthsSlider.MinorTicks = [];
            elseif FrequencyID == 3
                app.PulseWidthsSlider.Limits = [0 8];
                app.PulseWidthsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
                app.PulseWidthsSlider.MajorTickLabels = {'0', '32.13', '64.25', '96.38', '128.5', '160.63', '192.75', '224.88', '257', ''};
                app.PulseWidthsSlider.MinorTicks = [];
            end
        end

        % Value changed function: PulseWidthsSlider
        function PulseWidthChanged(app, event)
            PulseWidthID = round(app.PulseWidthsSlider.Value);
            app.PulseWidthsSlider.Value = PulseWidthID;
        end

        % Button pushed function: SendClockSignalButton
        function SendClockSignal(app, event)
            % FrequencyID: 
            %   1 -> 30.64 Hz 
            %   2 -> 61.04 Hz 
            %   3 -> 122.08 Hz
            FrequencyID = app.FrequencyHzSlider.Value;
            
            % PulseWidthID: 'value' to pass to arduino's 
            %   analogWrite(pin, value) function
            PulseWidthID = app.PulseWidthsSlider.Value;
            
            % Pass frequency and pulse width to the arduino by encoding 
            %   both values into a single uint8. Number in tens place is
            %   FrequencyID, number in ones place is PulseWidthID.
            encoded_val = FrequencyID*10 + PulseWidthID;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyHzSliderLabel = uilabel(app.UIFigure);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.UIFigure);
            app.FrequencyHzSlider.Limits = [1 3];
            app.FrequencyHzSlider.MajorTicks = [1 2 3];
            app.FrequencyHzSlider.MajorTickLabels = {'30.52', '61.04', '122.07'};
            app.FrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyChanged, true);
            app.FrequencyHzSlider.MinorTicks = [];
            app.FrequencyHzSlider.Value = 1;
            app.PulseWidthsSliderLabel = uilabel(app.UIFigure);
            app.PulseWidthsSliderLabel.Text = {'Pulse Width (μs)'; ''};
            app.PulseWidthsSlider = uislider(app.UIFigure);
            app.PulseWidthsSlider.Limits = [0 2];
            app.PulseWidthsSlider.MajorTicks = [0 1 2];
            app.PulseWidthsSlider.MajorTickLabels = {'0', '128.5', '257'};
            app.PulseWidthsSlider.ValueChangedFcn = createCallbackFcn(app, @PulseWidthChanged, true);
            app.PulseWidthsSlider.MinorTicks = [];
            app.SendClockSignalButton = uibutton(app.UIFigure, 'push');
            app.SendClockSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SendClockSignal, true);
            app.SendClockSignalButton.Text = {'Send Clock Signal'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_clock_signal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end